classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        ExamplesTab             matlab.ui.container.Tab
        UITable                 matlab.ui.control.Table
        ExamplesListBox         matlab.ui.control.ListBox
        ExamplesListBoxLabel    matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
        CBerrorTab              matlab.ui.container.Tab
        Panel                   matlab.ui.container.Panel
        PlotButton              matlab.ui.control.Button
        ScaleEditField          matlab.ui.control.NumericEditField
        ScaleEditFieldLabel     matlab.ui.control.Label
        maxPEditField_5         matlab.ui.control.NumericEditField
        maxPEditField_5Label    matlab.ui.control.Label
        CBsolveerrorTab         matlab.ui.container.Tab
        Button                  matlab.ui.control.Button
        nEditField              matlab.ui.control.NumericEditField
        nEditFieldLabel         matlab.ui.control.Label
        x_scaleEditField        matlab.ui.control.NumericEditField
        x_scaleEditFieldLabel   matlab.ui.control.Label
        scaleEditField          matlab.ui.control.NumericEditField
        scaleEditFieldLabel     matlab.ui.control.Label
        maxPEditField_4         matlab.ui.control.NumericEditField
        maxPEditField_4Label    matlab.ui.control.Label
        UIAxes2                 matlab.ui.control.UIAxes
        CBdependencyerrorTab    matlab.ui.container.Tab
        Panel_2                 matlab.ui.container.Panel
        PlotButton_2            matlab.ui.control.Button
        nEditField_2            matlab.ui.control.NumericEditField
        nEditField_2Label       matlab.ui.control.Label
        maxScaleEditField       matlab.ui.control.NumericEditField
        maxScaleEditFieldLabel  matlab.ui.control.Label
        maxPEditField_6         matlab.ui.control.NumericEditField
        maxPEditField_6Label    matlab.ui.control.Label
    end

    
    properties (Access = private)
        examples;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.examples = getExamples();
        end

        % Value changed function: ExamplesListBox
        function ExamplesListBoxValueChanged(app, event)
            idx = app.ExamplesListBox.Value;
            numericIdx = str2double(idx);
            app.UITable.Data = app.examples{numericIdx};
            x_scale_values = 20;
            errors = cbSolveErrorForA(app.examples{numericIdx}, x_scale_values, 10);
            plot(app.UIAxes, 1:x_scale_values, errors);
            xlabel(app.UIAxes, 'x_scale');
            ylabel(app.UIAxes, 'Error');
            title(app.UIAxes, 'Error vs. x_scale');
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            a = app.maxPEditField_5.Value;
            b = app.ScaleEditField.Value;
            errorMatrix = cbError(a, b);
            
            hm = heatmap(app.Panel, errorMatrix);
            colormap(hm, 'jet'); % Set the colormap for the heatmap
            colorbar(hm); % Adds a color bar to indicate the scale
            
            % Set axes labels for the heatmap
            hm.XLabel = 'Scale (b)';
            hm.YLabel = 'P value (a)';
            hm.Title = 'Heatmap of Average Errors';
            
            % Adjusting the axes to show all columns and rows clearly
            hm.CellLabelFormat = '%.2f';
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            cbSolveErrorPlot(app.UIAxes2, app.maxPEditField_4.Value, app.scaleEditField.Value, app.x_scaleEditField.Value, app.nEditField.Value);
        end

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)
            maxP = app.maxPEditField_6.Value;
            maxScale = app.maxScaleEditField.Value;
            n = app.nEditField_2.Value;
            errorMatrix = cbDependencyError(maxP, maxScale, n);
            
            hm = heatmap(app.Panel_2, errorMatrix);
            colormap(hm, 'jet'); % Set the colormap for the heatmap
            colorbar(hm); % Adds a color bar to indicate the scale
            
            % Set axes labels for the heatmap
            hm.XLabel = 'Scale (b)';
            hm.YLabel = 'P value (a)';
            hm.Title = 'Heatmap of Average Errors';
            
            % Adjusting the axes to show all columns and rows clearly
            hm.CellLabelFormat = '%.2f';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ExamplesTab = uitab(app.TabGroup);
            app.ExamplesTab.Title = 'Examples';
            app.UIAxes = uiaxes(app.ExamplesTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ExamplesListBoxLabel = uilabel(app.ExamplesTab);
            app.ExamplesListBoxLabel.Text = 'Examples';
            app.ExamplesListBox = uilistbox(app.ExamplesTab);
            app.ExamplesListBox.Items = {'1', '2', '3', '4', '5', '6'};
            app.ExamplesListBox.ValueChangedFcn = createCallbackFcn(app, @ExamplesListBoxValueChanged, true);
            app.ExamplesListBox.Value = '1';
            app.UITable = uitable(app.ExamplesTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.CBerrorTab = uitab(app.TabGroup);
            app.CBerrorTab.Title = 'CB error';
            app.maxPEditField_5Label = uilabel(app.CBerrorTab);
            app.maxPEditField_5Label.Text = 'maxP';
            app.maxPEditField_5 = uieditfield(app.CBerrorTab, 'numeric');
            app.maxPEditField_5.RoundFractionalValues = 'on';
            app.ScaleEditFieldLabel = uilabel(app.CBerrorTab);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField = uieditfield(app.CBerrorTab, 'numeric');
            app.ScaleEditField.RoundFractionalValues = 'on';
            app.PlotButton = uibutton(app.CBerrorTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.Panel = uipanel(app.CBerrorTab);
            app.Panel.Title = 'Panel';
            app.CBsolveerrorTab = uitab(app.TabGroup);
            app.CBsolveerrorTab.Title = 'CB solve error';
            app.UIAxes2 = uiaxes(app.CBsolveerrorTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.maxPEditField_4Label = uilabel(app.CBsolveerrorTab);
            app.maxPEditField_4Label.Text = 'maxP';
            app.maxPEditField_4 = uieditfield(app.CBsolveerrorTab, 'numeric');
            app.maxPEditField_4.RoundFractionalValues = 'on';
            app.scaleEditFieldLabel = uilabel(app.CBsolveerrorTab);
            app.scaleEditFieldLabel.Text = 'scale';
            app.scaleEditField = uieditfield(app.CBsolveerrorTab, 'numeric');
            app.scaleEditField.RoundFractionalValues = 'on';
            app.x_scaleEditFieldLabel = uilabel(app.CBsolveerrorTab);
            app.x_scaleEditFieldLabel.Text = 'x_scale';
            app.x_scaleEditField = uieditfield(app.CBsolveerrorTab, 'numeric');
            app.x_scaleEditField.RoundFractionalValues = 'on';
            app.nEditFieldLabel = uilabel(app.CBsolveerrorTab);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.CBsolveerrorTab, 'numeric');
            app.nEditField.RoundFractionalValues = 'on';
            app.Button = uibutton(app.CBsolveerrorTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.CBdependencyerrorTab = uitab(app.TabGroup);
            app.CBdependencyerrorTab.Title = 'CB dependency error';
            app.maxPEditField_6Label = uilabel(app.CBdependencyerrorTab);
            app.maxPEditField_6Label.Text = 'maxP';
            app.maxPEditField_6 = uieditfield(app.CBdependencyerrorTab, 'numeric');
            app.maxPEditField_6.RoundFractionalValues = 'on';
            app.maxScaleEditFieldLabel = uilabel(app.CBdependencyerrorTab);
            app.maxScaleEditFieldLabel.Text = 'maxScale';
            app.maxScaleEditField = uieditfield(app.CBdependencyerrorTab, 'numeric');
            app.maxScaleEditField.RoundFractionalValues = 'on';
            app.nEditField_2Label = uilabel(app.CBdependencyerrorTab);
            app.nEditField_2Label.Text = 'n';
            app.nEditField_2 = uieditfield(app.CBdependencyerrorTab, 'numeric');
            app.nEditField_2.RoundFractionalValues = 'on';
            app.PlotButton_2 = uibutton(app.CBdependencyerrorTab, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Text = 'Plot';
            app.Panel_2 = uipanel(app.CBdependencyerrorTab);
            app.Panel_2.Title = 'Panel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end